classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Group10ComputerVisionChallengeUIFigure  matlab.ui.Figure
        SETUPPanel                     matlab.ui.container.Panel
        SceneButton                    matlab.ui.control.Button
        BackgroundButton               matlab.ui.control.Button
        StorePathButton                matlab.ui.control.Button
        Scene                          matlab.ui.control.Label
        Background                     matlab.ui.control.Label
        Store_Path                     matlab.ui.control.Label
        LeftImageButtonGroup           matlab.ui.container.ButtonGroup
        Sequence1Button                matlab.ui.control.ToggleButton
        Sequence2Button_left           matlab.ui.control.ToggleButton
        RightImageButtonGroup          matlab.ui.container.ButtonGroup
        Sequence2Button_right          matlab.ui.control.ToggleButton
        Sequence3Button                matlab.ui.control.ToggleButton
        StoreVideoCheckBox             matlab.ui.control.CheckBox
        ChooseascenefolderfromoneofthechokepointdatasetsLabel  matlab.ui.control.Label
        ChooseabackgroundLabel         matlab.ui.control.Label
        ChooseastartpointLabel         matlab.ui.control.Label
        ChooseaRenderingModeLabel      matlab.ui.control.Label
        WiththisselectionthefirstimageisselectedLabel  matlab.ui.control.Label
        ThismightbeanimageoragifLabel  matlab.ui.control.Label
        ChoosetheleftandrightimageLabel  matlab.ui.control.Label
        OutputVideoOptionsLabel        matlab.ui.control.Label
        StorethevideoLabel             matlab.ui.control.Label
        TheselectionofanoutputpathisoptionalIfnopathisselectedLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        RenderingModeDropDownLabel     matlab.ui.control.Label
        RenderingModeDropDown          matlab.ui.control.DropDown
        StartPointEditFieldLabel       matlab.ui.control.Label
        StartPointEditField            matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        ShowResultButton               matlab.ui.control.Button
        STATUSPanel                    matlab.ui.container.Panel
        ProcessingLampLabel            matlab.ui.control.Label
        ProcessingLamp                 matlab.ui.control.Lamp
        VideoRenderedLampLabel         matlab.ui.control.Label
        VideoRenderedLamp              matlab.ui.control.Lamp
        StoppedLampLabel               matlab.ui.control.Label
        StoppedLamp                    matlab.ui.control.Lamp
        current_Frame                  matlab.ui.control.Label
        elapsed_time_label             matlab.ui.control.Label
        PREVIEWPanel                   matlab.ui.container.Panel
        left_image                     matlab.ui.control.Image
        right_image                    matlab.ui.control.Image
        background_image               matlab.ui.control.Image
        LeftImageLabel                 matlab.ui.control.Label
        RightImageLabel                matlab.ui.control.Label
        BackgroundLabel                matlab.ui.control.Label
        PREVIEWPanel_2                 matlab.ui.container.Panel
        Preview_Image                  matlab.ui.control.Image
        PauseButton                    matlab.ui.control.StateButton
        CancelButton                   matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', 'L', 1);
            assignin('base', 'R', 2);
            assignin('base', 'start', 1);
            assignin('base', 'render_mode', 'substitute');
            assignin('base', 'store', 0);
            assignin('base', 'N', 5); % N can onbly be set as a default
            
            % set empty variables for directories
            assignin('base', 'src', '');
            assignin('base', 'dest', 'output.avi');
            assignin('base', 'background_path', '');
            
            % Indicator Setup
            app.ProcessingLamp.Enable = 'off';
            app.VideoRenderedLamp.Enable = 'off';
            app.PREVIEWPanel_2.Visible = 'off';
            %store path labels and buttons
            app.TheselectionofanoutputpathisoptionalIfnopathisselectedLabel.Visible = 'off';
            app.Label.Visible = 'off';
            app.StorePathButton.Visible = 'off';
            app.Store_Path.Visible = 'off';
            % disable run button
            app.RunButton.Enable = 'off';
            %isable current frame and elapsed time labels
            app.current_Frame.Visible = 'off';
            app.elapsed_time_label.Visible = 'off';
        end

        % Button pushed function: SceneButton
        function SceneButtonPushed(app, event)
            
            % open path selection dialog box
            %app.Group10ComputerVisionChallengeUIFigure.Visible = 'off';
            scene_path = uigetdir();
            %app.Group10ComputerVisionChallengeUIFigure.Visible = 'on'; 
            %drawnow;
            figure(app.Group10ComputerVisionChallengeUIFigure)

            
            % only if the selection hasn't been canceled
            if scene_path ~= 0
                % store scene_path to workspace variables
                % scene path: src
                assignin('base', 'src', scene_path)
                
                % Update Label
                text = "Path: " + scene_path;
                app.Scene.Text = text;
                drawnow
                
                % update preview images
                L = evalin('base','L');
                R = evalin('base','R');
                src = evalin('base','src');
                start = evalin('base','start');
                background_path = evalin('base', 'background_path');
                
                % Enable RUN button if src and background_path are set
                if background_path ~= "" && src ~= ""
                    app.RunButton.Enable = 'on';
                end
                
                % copied from ImageReader.m
                left_image_path = strcat(src, src((end - 6):end), '_C', num2str(L), '/');
                right_image_path = strcat(src, src((end - 6):end), '_C', num2str(R), '/');
                
                % Read filenames from text-file in respective folder
                f = fopen(strcat(left_image_path, 'all_file.txt'));
                all_files = textscan(f, '%s');
                left_image_name = all_files{1}(start);
                fclose(f);
                
                f = fopen(strcat(right_image_path, 'all_file.txt'));
                all_files = textscan(f, '%s');
                right_image_name = all_files{1}(start);
                fclose(f);
                
                % assign path to image views
                app.left_image.ImageSource = strcat(left_image_path, left_image_name{1});
                app.right_image.ImageSource = strcat(right_image_path, right_image_name{1});
                
                %set background to white
                app.left_image.BackgroundColor = 'white';
                app.right_image.BackgroundColor = 'white';
            end
        end

        % Button pushed function: BackgroundButton
        function BackgroundButtonPushed(app, event)
            % open path selection dialog box
            background_path = uigetfile({'*gif';'*jpg';'*png'});
            %background_path = uigetfile({'*.tif';'*.jpg';'*.png';'*.gif*'},'File Selector');
            figure(app.Group10ComputerVisionChallengeUIFigure)
            
            % only if the selection hasn't been canceled
            if background_path ~= 0
                % store scene_path to workspace variables
                % background path: background_path
                assignin('base', 'background_path', background_path)
                
                % update image preview
                    % distinguish between gif and image, load corrisponding
                    % data
                if background_path(end-2:end) == "gif"
                    [image, map] = imread(background_path,'frames',1);
                    app.background_image.ImageSource = ind2rgb8(image(:,:,:,1),map);
                else
                    app.background_image.ImageSource = imread(background_path);
                end
                % disable gray background
                app.background_image.BackgroundColor = 'white';
                drawnow;
                
                % Update Label
                text = "Path: " + background_path;
                app.Background.Text = text;
                drawnow
                
                src = evalin('base','src');
                % Enable RUN button if src and background_path are set
                if background_path ~= "" && src ~= ""
                    app.RunButton.Enable = 'on';
                end
            end
        end

        % Value changed function: RenderingModeDropDown
        function RenderingModeDropDownValueChanged(app, event)
            value = app.RenderingModeDropDown.Value;
            % store rendering mdoe to workspace variables
            % rendering mdoe: mode
            assignin('base', 'render_mode', value)
        end

        % Value changed function: StartPointEditField
        function StartPointEditFieldValueChanged(app, event)
            value = app.StartPointEditField.Value;
            % store start to workspace variables
            % start: start
            assignin('base', 'start', value)
            
            % update preview images
            L = evalin('base','L');
            R = evalin('base','R');
            src = evalin('base','src');
            start = evalin('base','start');
            if src ~= "" % update image Views only if source path is not empty
                % copied from ImageReader.m
                left_image_path = strcat(src, src((end - 6):end), '_C', num2str(L), '/');
                right_image_path = strcat(src, src((end - 6):end), '_C', num2str(R), '/');
                
                % Read filenames from text-file in respective folder
                f = fopen(strcat(left_image_path, 'all_file.txt'));
                all_files = textscan(f, '%s');
                left_image_name = all_files{1}(start);
                fclose(f);
                
                f = fopen(strcat(right_image_path, 'all_file.txt'));
                all_files = textscan(f, '%s');
                right_image_name = all_files{1}(start);
                fclose(f);
                
                % assign path to image views
                app.left_image.ImageSource = strcat(left_image_path, left_image_name{1});
                app.right_image.ImageSource = strcat(right_image_path, right_image_name{1});
            end
        end

        % Button pushed function: StorePathButton
        function StorePathButtonPushed(app, event)
            
            % open path selection dialog box
            store_path = uigetdir();
            figure(app.Group10ComputerVisionChallengeUIFigure)
            
            % only if the selection hasn't been canceled
            if store_path ~= 0
                % store scene_path to workspace variables
                % store path: dest
                output_path = store_path + "/output.avi";
                assignin('base', 'dest', output_path);
                % Update Label
                text = "Path: " + output_path;
                app.Store_Path.Text = text;
                %drawnow
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %reset cancel button bevore starting. Fixes the issue of not
            %restarting after already finishing once
            app.CancelButton.Value = 0;
            % fetch standard variables from base workspace
            L = evalin('base','L');
            R = evalin('base','R');
            render_mode = evalin('base','render_mode');
            N = evalin('base','N');
            start = evalin('base','start');
            store = evalin('base','store');
            dest = evalin('base','dest');
            
            % set status to 0
            status = 0;
            
            % set pause button to 0, it might be still enabled from a
            % bevore ongoing rendering procedure
            app.PauseButton.Value = 0;
            
            % check all directories. If empty string -> error state
            
            % src
            if isempty(evalin('base','src')) == 1
                disp('src missing!')
                status = 1;
            else % asign string if not empty
                src = evalin('base','src');
            end
            
            % dest
            if isempty(evalin('base','background_path')) == 1
                disp('background_path missing!')
                status = 1;
            else % asign string if not empty
                bg_name = evalin('base','background_path');
            end
            
            app.ProcessingLamp.Enable = 'on'; % indicate Matlab is processing the files
            drawnow;
            
            if status == 0
                app.PREVIEWPanel.Visible = 'off';
                app.PREVIEWPanel_2.Visible = 'on';
                app.ShowResultButton.Enable = 'off';
                drawnow;
                
                gui_config;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%CHANGES BY Johannes Teutsch%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                %% Computer Vision Challenge 2020 challenge.m
                    
                            %% Start timer here
                            t_start = 0;
                            t_start = tic;
                            
                            %%%%%%%%%%%NEW%%%%%%%%%%%%%%%%%
                            elapsed_time = 0; 
                            %%%%%%%%%%%NEW%%%%%%%%%%%%%%%%%
                            
                            % Initialize indices
                            loop = 0;
                            i = 0;
                            
                            % Initialize height and width
                            height_px = 600;
                            width_px = 800;
                            
                            %if store
                              % Create a movie array
                              a = dir([src, '/', src(end - 6:end), '_C1', '/*.jpg']);
                              nr_total_frames = numel(a);
                              
                              % Sequence of RGB images (height x width x 3 x frames)
                              movie = zeros(height_px, width_px, 3, nr_total_frames, 'uint8');
                            %end
                            
                            if gif
                              % Define gif size
                              num_gif_images = size(gif_image_raw, 4);
                            
                              % construct an empty movie by initializing dimensions
                              gif_image = zeros(height_px, width_px, 3, num_gif_images, 'uint8');
                            
                              for j = 1:num_gif_images
                                % choose frame and convert it
                                % Multiply with 255: colormaps read in from GIFs represent colors
                                % in the range 0 to 1, rather than integers 0 to 255
                                gif_frame_raw = gif_image_raw(:, :, :, j);
                                gif_frame_rgb = uint8(255 * ind2rgb(gif_frame_raw, cmap));
                            
                                % resize image and define background
                                gif_image(:, :, :, j) = imresize(gif_frame_rgb, [height_px, width_px]);
                              end
                            
                            end
                            
                            %% Generate Movie
                            app.current_Frame.Visible = 'on';
                            while loop ~= 1
                              i = i + 1;
                              
                              current_frame = i;
                              app.current_Frame.Text = "Frame: " + current_frame; %+ " / " + number_of_frames;
                              drawnow;
                              
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%% CHANGES in challenge.m %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                             
                                if app.CancelButton.Value
                                    % reset cancel button
                                    app.CancelButton.Value = 0;
                                    loop = 1;
                                    return
                                end
                                    
                              %check value of start/stop button. If 1 -> stop
                              if(app.PauseButton.Value)
                                  % red signal light
                                  app.StoppedLamp.Enable = 'on';
                                  app.ProcessingLamp.Enable = 'off';
                                  %stop time measurment
                                  elapsed_time = toc(t_start) + elapsed_time;
                                  
                                  %wait until start button pushed to reset flag
                                  waitfor(app.PauseButton,'value', false)  
                                  
                                  %restart time measurment
                                  t_start = tic;
                              end
                              % red signal light
                              app.StoppedLamp.Enable = 'off';
                              app.ProcessingLamp.Enable = 'on';
                     
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%% CHANGES in challenge.m %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                              
                              % Get next image tensors
                              [left, right, loop] = ir.next();
                              % Generate binary mask
                              mask = segmentation(left, right);
                            
                              % GIF background?
                              if gif
                                bg = gif_image(:, :, :, mod(i, num_gif_images) + 1);
                              end
                            
                              % Render new frame
                              if store
                                movie(:, :, :, i) = render(left(:, :, 1:3), mask, bg, render_mode);
                                %imshow(movie(:, :, :, i));  %NOT NECESSARY
                                app.Preview_Image.ImageSource = movie(:, :, :, i);
                                drawnow;
                              else
                                %also if store = 0, this should be displayed!
                                movie(:, :, :, i) = render(left(:, :, 1:3), mask, bg, render_mode);
                                % Update Preview
                                app.Preview_Image.ImageSource = movie(:, :, :, i);
                                drawnow;
                              end
                            
                            end
                            
                            %% Stop timer here
                            %%%%%%%%%%%NEW%%%%%%%%%%%%%%%%%
                            elapsed_time = toc(t_start) + elapsed_time;
                            %%%%%%%%%%%NEW%%%%%%%%%%%%%%%%%
                            
                            % store movie as a variable for playback
                            assignin('base', 'movie', movie(:, :, :, 1:i));
                            
                            fprintf('Elapsed time: %.3f seconds = %.3f minutes\n', elapsed_time, elapsed_time / 60);
                            app.elapsed_time_label.Visible = 'on';
                            app.elapsed_time_label.Text = "Elapsed Time: " + elapsed_time + "sec., " + elapsed_time/60 + "min.";
                            drawnow;
                            
                            %% Write Movie to Disk
                            if store
                              % default frame rate for the VideoWriter object is 30 frames per second
                              v = VideoWriter(dest, 'Motion JPEG AVI');
                              v.Quality = 75;
                              open(v);
                            
                              for j = 1:i
                                frame = im2frame(movie(:, :, :, j));
                                writeVideo(v, frame);
                              end
                            
                              close(v);
                            end
                            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%CHANGES%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                %set Button visibility
                app.ShowResultButton.Enable = 'on';
                app.ProcessingLamp.Enable = 'off';
                app.VideoRenderedLamp.Enable = 'on';
                app.RunButton.Enable = 'off';
                app.PauseButton.Enable = 'off';
                
                %clear current workspace
                
                %app.CancelButton.Enable = 'off';
                %assignin('base', 'result', result);
            else
                errordlg('Parameters missing','Error')
                % bring gui to the front again
                figure(app.Group10ComputerVisionChallengeUIFigure)
            end
            
        end

        % Selection changed function: LeftImageButtonGroup
        function LeftImageButtonGroupSelectionChanged(app, event)
            selectedButton = app.LeftImageButtonGroup.SelectedObject;
            
            % Assign selected Button to Sequence number
            if selectedButton.Tag == "sq1"
                L = 1;
            else
                L = 2;
            end
            
            % Store selected Button to Workspace
            assignin('base', 'L', L)
            
            % Change selection of Right image if left image is image 2
            if selectedButton.Tag == "sq2"
                app.RightImageButtonGroup.SelectedObject = app.Sequence3Button; % Set Button UI
                assignin('base', 'R', 3) % Write to Workspace
                drawnow % refresh UI
            end
            
            % update preview images
            L = evalin('base','L');
            R = evalin('base','R');
            src = evalin('base','src');
            start = evalin('base','start');
                
            if src ~= ""  % update image Views only if source path is not empty
                % copied from ImageReader.m
                left_image_path = strcat(src, src((end - 6):end), '_C', num2str(L), '/');
                right_image_path = strcat(src, src((end - 6):end), '_C', num2str(R), '/');
                
                % Read filenames from text-file in respective folder
                f = fopen(strcat(left_image_path, 'all_file.txt'));
                all_files = textscan(f, '%s');
                left_image_name = all_files{1}(start);
                fclose(f);
                
                f = fopen(strcat(right_image_path, 'all_file.txt'));
                all_files = textscan(f, '%s');
                right_image_name = all_files{1}(start);
                fclose(f);
                
                % assign path to image views
                app.left_image.ImageSource = strcat(left_image_path, left_image_name{1});
                app.right_image.ImageSource = strcat(right_image_path, right_image_name{1});
            end
        end

        % Selection changed function: RightImageButtonGroup
        function RightImageButtonGroupSelectionChanged(app, event)
            selectedButton = app.RightImageButtonGroup.SelectedObject;
            
            % Assign selected Button to Sequence number
            if selectedButton.Tag == "sq2"
                R = 2;
            else
                R = 3;
            end
            
            % Store selected Button to Workspace
            assignin('base', 'R', R);
            
            % Change selection of Right image if left image is image 2
            if selectedButton.Tag == "sq2"
                app.LeftImageButtonGroup.SelectedObject = app.Sequence1Button; % Set Button UI
                assignin('base', 'L', 1) % Write to Workspace
                drawnow % refresh UI
            end
            
            % update preview images
            L = evalin('base','L');
            R = evalin('base','R');
            src = evalin('base','src');
            start = evalin('base','start');
            
            if src ~= ""  % update image Views only if source path is not empty
                % copied from ImageReader.m
                left_image_path = strcat(src, src((end - 6):end), '_C', num2str(L), '/');
                right_image_path = strcat(src, src((end - 6):end), '_C', num2str(R), '/');
                
                % Read filenames from text-file in respective folder
                f = fopen(strcat(left_image_path, 'all_file.txt'));
                all_files = textscan(f, '%s');
                left_image_name = all_files{1}(start);
                fclose(f);
                
                f = fopen(strcat(right_image_path, 'all_file.txt'));
                all_files = textscan(f, '%s');
                right_image_name = all_files{1}(start);
                fclose(f);
                
                % assign path to image views
                app.left_image.ImageSource = strcat(left_image_path, left_image_name{1});
                app.right_image.ImageSource = strcat(right_image_path, right_image_name{1});
            end
        end

        % Value changed function: StoreVideoCheckBox
        function StoreVideoCheckBoxValueChanged(app, event)
            value = app.StoreVideoCheckBox.Value;
            
            assignin('base', 'store', value) % Write to Workspace
            
            % set storepath options visible or not visible depending
            % StoreVideoCheckbox state
            if value == 1
                app.TheselectionofanoutputpathisoptionalIfnopathisselectedLabel.Visible = 'on';
                app.Label.Visible = 'on';
                app.StorePathButton.Visible = 'on';
                app.Store_Path.Visible = 'on';
            else
                app.TheselectionofanoutputpathisoptionalIfnopathisselectedLabel.Visible = 'off';
                app.Label.Visible = 'off';
                app.StorePathButton.Visible = 'off';
                app.Store_Path.Visible = 'off';
            end
            
        end

        % Button pushed function: ShowResultButton
        function ShowResultButtonPushed(app, event)
            % open videoplayer with movie variable
            movie = evalin('base', 'movie');
            implay(movie); % open video player
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged2(app, event)
            value = app.PauseButton.Value;
            % Code by Johannes Teutsch
            if(value)
                app.PauseButton.Text = 'Play';          %Text if button is pressed
            else
                app.PauseButton.Text = 'Pause';           %Text if button is not pressed
            end
        end

        % Value changed function: CancelButton
        function CancelButtonValueChanged(app, event)
            value = app.CancelButton.Value;
            
            if value
                app.PREVIEWPanel_2.Visible = 'off';
                app.PREVIEWPanel.Visible = 'on';
                app.RunButton.Enable = 'on';
                app.ShowResultButton.Enable = 'off';
                app.elapsed_time_label.Visible = 'off';
                app.PauseButton.Enable = 'on';
                % hide frame counter
                app.current_Frame.Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Group10ComputerVisionChallengeUIFigure = uifigure('Visible', 'off');
            app.Group10ComputerVisionChallengeUIFigure.Name = 'Group 10 - Computer Vision Challenge';
            app.SETUPPanel = uipanel(app.Group10ComputerVisionChallengeUIFigure);
            app.SETUPPanel.Title = 'SETUP';
            app.SceneButton = uibutton(app.SETUPPanel, 'push');
            app.SceneButton.ButtonPushedFcn = createCallbackFcn(app, @SceneButtonPushed, true);
            app.SceneButton.Text = 'Scene';
            app.BackgroundButton = uibutton(app.SETUPPanel, 'push');
            app.BackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundButtonPushed, true);
            app.BackgroundButton.Text = 'Background';
            app.StorePathButton = uibutton(app.SETUPPanel, 'push');
            app.StorePathButton.ButtonPushedFcn = createCallbackFcn(app, @StorePathButtonPushed, true);
            app.StorePathButton.Text = 'Store Path';
            app.Scene = uilabel(app.SETUPPanel);
            app.Scene.Text = 'Not selected yet';
            app.Background = uilabel(app.SETUPPanel);
            app.Background.Text = 'Not selected yet';
            app.Store_Path = uilabel(app.SETUPPanel);
            app.Store_Path.Text = 'Not selected yet';
            app.LeftImageButtonGroup = uibuttongroup(app.SETUPPanel);
            app.LeftImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LeftImageButtonGroupSelectionChanged, true);
            app.LeftImageButtonGroup.Title = 'Left Image';
            app.Sequence1Button = uitogglebutton(app.LeftImageButtonGroup);
            app.Sequence1Button.Tag = 'sq1';
            app.Sequence1Button.Text = 'Sequence 1';
            app.Sequence1Button.Value = true;
            app.Sequence2Button_left = uitogglebutton(app.LeftImageButtonGroup);
            app.Sequence2Button_left.Tag = 'sq2';
            app.Sequence2Button_left.Text = 'Sequence 2';
            app.RightImageButtonGroup = uibuttongroup(app.SETUPPanel);
            app.RightImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RightImageButtonGroupSelectionChanged, true);
            app.RightImageButtonGroup.Title = 'Right Image';
            app.Sequence2Button_right = uitogglebutton(app.RightImageButtonGroup);
            app.Sequence2Button_right.Tag = 'sq2';
            app.Sequence2Button_right.Text = 'Sequence 2';
            app.Sequence2Button_right.Value = true;
            app.Sequence3Button = uitogglebutton(app.RightImageButtonGroup);
            app.Sequence3Button.Tag = 'sq3';
            app.Sequence3Button.Text = 'Sequence 3';
            app.StoreVideoCheckBox = uicheckbox(app.SETUPPanel);
            app.StoreVideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @StoreVideoCheckBoxValueChanged, true);
            app.StoreVideoCheckBox.Text = 'Store Video';
            app.ChooseascenefolderfromoneofthechokepointdatasetsLabel = uilabel(app.SETUPPanel);
            app.ChooseascenefolderfromoneofthechokepointdatasetsLabel.Text = '1. Choose a scene folder from one of the chokepoint data sets';
            app.ChooseabackgroundLabel = uilabel(app.SETUPPanel);
            app.ChooseabackgroundLabel.Text = '2. Choose a background. ';
            app.ChooseastartpointLabel = uilabel(app.SETUPPanel);
            app.ChooseastartpointLabel.Text = '3. Choose a start point.';
            app.ChooseaRenderingModeLabel = uilabel(app.SETUPPanel);
            app.ChooseaRenderingModeLabel.Text = '4. Choose a Rendering Mode';
            app.WiththisselectionthefirstimageisselectedLabel = uilabel(app.SETUPPanel);
            app.WiththisselectionthefirstimageisselectedLabel.Text = 'With this selection, the first image is selected.';
            app.ThismightbeanimageoragifLabel = uilabel(app.SETUPPanel);
            app.ThismightbeanimageoragifLabel.Text = 'This might be an image or a gif.';
            app.ChoosetheleftandrightimageLabel = uilabel(app.SETUPPanel);
            app.ChoosetheleftandrightimageLabel.Text = '5. Choose the left and right image';
            app.OutputVideoOptionsLabel = uilabel(app.SETUPPanel);
            app.OutputVideoOptionsLabel.Text = '6. Output Video Options';
            app.StorethevideoLabel = uilabel(app.SETUPPanel);
            app.StorethevideoLabel.Text = 'Store the video';
            app.TheselectionofanoutputpathisoptionalIfnopathisselectedLabel = uilabel(app.SETUPPanel);
            app.TheselectionofanoutputpathisoptionalIfnopathisselectedLabel.Text = 'The selection of an output path is optional.';
            app.Label = uilabel(app.SETUPPanel);
            app.Label.Text = 'If no path is selected, the video will be stored to the matlab workspace folder';
            app.RenderingModeDropDownLabel = uilabel(app.SETUPPanel);
            app.RenderingModeDropDownLabel.Text = 'Rendering Mode';
            app.RenderingModeDropDown = uidropdown(app.SETUPPanel);
            app.RenderingModeDropDown.Items = {'foreground', 'background', 'overlay', 'substitute'};
            app.RenderingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @RenderingModeDropDownValueChanged, true);
            app.RenderingModeDropDown.Value = 'substitute';
            app.StartPointEditFieldLabel = uilabel(app.SETUPPanel);
            app.StartPointEditFieldLabel.Text = 'Start Point';
            app.StartPointEditField = uieditfield(app.SETUPPanel, 'numeric');
            app.StartPointEditField.Limits = [0 Inf];
            app.StartPointEditField.ValueDisplayFormat = '%.0f';
            app.StartPointEditField.ValueChangedFcn = createCallbackFcn(app, @StartPointEditFieldValueChanged, true);
            app.RunButton = uibutton(app.SETUPPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ShowResultButton = uibutton(app.SETUPPanel, 'push');
            app.ShowResultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultButtonPushed, true);
            app.ShowResultButton.Enable = 'off';
            app.ShowResultButton.Text = 'Show Result';
            app.STATUSPanel = uipanel(app.Group10ComputerVisionChallengeUIFigure);
            app.STATUSPanel.Title = 'STATUS';
            app.ProcessingLampLabel = uilabel(app.STATUSPanel);
            app.ProcessingLampLabel.Text = 'Processing';
            app.ProcessingLamp = uilamp(app.STATUSPanel);
            app.ProcessingLamp.Enable = 'off';
            app.VideoRenderedLampLabel = uilabel(app.STATUSPanel);
            app.VideoRenderedLampLabel.Text = 'Video Rendered';
            app.VideoRenderedLamp = uilamp(app.STATUSPanel);
            app.VideoRenderedLamp.Enable = 'off';
            app.StoppedLampLabel = uilabel(app.STATUSPanel);
            app.StoppedLampLabel.Text = 'Stopped';
            app.StoppedLamp = uilamp(app.STATUSPanel);
            app.StoppedLamp.Enable = 'off';
            app.current_Frame = uilabel(app.STATUSPanel);
            app.elapsed_time_label = uilabel(app.STATUSPanel);
            app.elapsed_time_label.Text = 'Label2';
            app.PREVIEWPanel = uipanel(app.Group10ComputerVisionChallengeUIFigure);
            app.PREVIEWPanel.Title = 'PREVIEW';
            app.left_image = uiimage(app.PREVIEWPanel);
            app.left_image.ImageSource = 'default_background.png';
            app.right_image = uiimage(app.PREVIEWPanel);
            app.right_image.ImageSource = 'default_background.png';
            app.background_image = uiimage(app.PREVIEWPanel);
            app.background_image.ImageSource = 'default_background.png';
            app.LeftImageLabel = uilabel(app.PREVIEWPanel);
            app.LeftImageLabel.Text = 'Left Image';
            app.RightImageLabel = uilabel(app.PREVIEWPanel);
            app.RightImageLabel.Text = 'Right Image';
            app.BackgroundLabel = uilabel(app.PREVIEWPanel);
            app.BackgroundLabel.Text = 'Background';
            app.PREVIEWPanel_2 = uipanel(app.Group10ComputerVisionChallengeUIFigure);
            app.PREVIEWPanel_2.Title = 'PREVIEW';
            app.Preview_Image = uiimage(app.PREVIEWPanel_2);
            app.Preview_Image.ImageSource = 'default_background.png';
            app.PauseButton = uibutton(app.PREVIEWPanel_2, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged2, true);
            app.PauseButton.Text = {'Pause'; ''};
            app.CancelButton = uibutton(app.PREVIEWPanel_2, 'state');
            app.CancelButton.ValueChangedFcn = createCallbackFcn(app, @CancelButtonValueChanged, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.Group10ComputerVisionChallengeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Group10ComputerVisionChallengeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Group10ComputerVisionChallengeUIFigure)
        end
    end
end